classdef kostky_ruta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Dice5EditField       matlab.ui.control.NumericEditField
        Dice5EditFieldLabel  matlab.ui.control.Label
        Dice4EditField       matlab.ui.control.NumericEditField
        Dice4EditFieldLabel  matlab.ui.control.Label
        Dice3EditField       matlab.ui.control.NumericEditField
        Dice3EditFieldLabel  matlab.ui.control.Label
        Dice2EditField       matlab.ui.control.NumericEditField
        Dice2EditFieldLabel  matlab.ui.control.Label
        Dice1EditField       matlab.ui.control.NumericEditField
        Dice1EditFieldLabel  matlab.ui.control.Label
        checkDice5CheckBox   matlab.ui.control.CheckBox
        checkDice4CheckBox   matlab.ui.control.CheckBox
        checkDice3CheckBox   matlab.ui.control.CheckBox
        checkDice2CheckBox   matlab.ui.control.CheckBox
        checkDice1CheckBox   matlab.ui.control.CheckBox
        rollButton           matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        dice_value = [0 0 0 0 0]% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         %%  app.dice_value = [0 0 0 0 0];
        end

        % Value changed function: rollButton
        function rollButtonValueChanged(app, event)
            value = app.rollButton.Value;
            dices = [ app.Dice1EditField app.Dice2EditField  app.Dice3EditField app.Dice4EditField app.Dice5EditField];
            checks = [app.checkDice1CheckBox app.checkDice2CheckBox app.checkDice3CheckBox app.checkDice4CheckBox app.checkDice5CheckBox ];
            %%dice_value = randi(6,5,1);

            for i = 1 : length( dices )
                if checks(i).Value
                    app.dice_value(i) = ceil(rand*6);
                end

            end
            for i = 1 : length( dices )
                dices(i).Value = app.dice_value(i);
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.rollButton = uibutton(app.GridLayout, 'state');
            app.rollButton.ValueChangedFcn = createCallbackFcn(app, @rollButtonValueChanged, true);
            app.rollButton.Text = 'roll';
            app.checkDice1CheckBox = uicheckbox(app.GridLayout);
            app.checkDice1CheckBox.Text = 'checkDice1';
            app.checkDice1CheckBox.Value = true;
            app.checkDice2CheckBox = uicheckbox(app.GridLayout);
            app.checkDice2CheckBox.Text = 'checkDice2';
            app.checkDice2CheckBox.Value = true;
            app.checkDice3CheckBox = uicheckbox(app.GridLayout);
            app.checkDice3CheckBox.Text = 'checkDice3';
            app.checkDice3CheckBox.Value = true;
            app.checkDice4CheckBox = uicheckbox(app.GridLayout);
            app.checkDice4CheckBox.Text = 'checkDice4';
            app.checkDice4CheckBox.Value = true;
            app.checkDice5CheckBox = uicheckbox(app.GridLayout);
            app.checkDice5CheckBox.Text = 'checkDice5';
            app.checkDice5CheckBox.Value = true;
            app.Dice1EditFieldLabel = uilabel(app.GridLayout);
            app.Dice1EditFieldLabel.Text = 'Dice1';
            app.Dice1EditField = uieditfield(app.GridLayout, 'numeric');
            app.Dice1EditField.Editable = 'off';
            app.Dice2EditFieldLabel = uilabel(app.GridLayout);
            app.Dice2EditFieldLabel.Text = 'Dice2';
            app.Dice2EditField = uieditfield(app.GridLayout, 'numeric');
            app.Dice2EditField.Editable = 'off';
            app.Dice3EditFieldLabel = uilabel(app.GridLayout);
            app.Dice3EditFieldLabel.Text = 'Dice3';
            app.Dice3EditField = uieditfield(app.GridLayout, 'numeric');
            app.Dice3EditField.Editable = 'off';
            app.Dice4EditFieldLabel = uilabel(app.GridLayout);
            app.Dice4EditFieldLabel.Text = 'Dice4';
            app.Dice4EditField = uieditfield(app.GridLayout, 'numeric');
            app.Dice4EditField.Editable = 'off';
            app.Dice5EditFieldLabel = uilabel(app.GridLayout);
            app.Dice5EditFieldLabel.Text = 'Dice5';
            app.Dice5EditField = uieditfield(app.GridLayout, 'numeric');
            app.Dice5EditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kostky_ruta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end